classdef cerestim_dbs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CereStimDBSUIFigure        matlab.ui.Figure
        RefreshButton              matlab.ui.control.Button
        DropDown                   matlab.ui.control.DropDown
        ConnectButton              matlab.ui.control.Button
        AmplitudeuAEditFieldLabel  matlab.ui.control.Label
        AmplitudeuAEditField       matlab.ui.control.NumericEditField
        WidthuSEditFieldLabel      matlab.ui.control.Label
        WidthuSEditField           matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel  matlab.ui.control.Label
        FrequencyHzEditField       matlab.ui.control.NumericEditField
        FinalDursEditFieldLabel    matlab.ui.control.Label
        FinalDursEditField         matlab.ui.control.NumericEditField
        RampDursEditFieldLabel     matlab.ui.control.Label
        RampDursEditField          matlab.ui.control.NumericEditField
        ElectrodeEditFieldLabel    matlab.ui.control.Label
        ElectrodeEditField         matlab.ui.control.NumericEditField
        InterphaseDropDownLabel    matlab.ui.control.Label
        InterphaseDropDown         matlab.ui.control.DropDown
        PolarityDropDownLabel      matlab.ui.control.Label
        PolarityDropDown           matlab.ui.control.DropDown
        GenerateButton             matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StatusLampLabel            matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
    end


    properties (Access = private)
        stimulator % Description
    end

    methods (Access = private)
    
        function params_changed(app)
            app.stimulator.stop();
            app.StatusLamp.Color = 'yellow';
            app.StartButton.Enable = 'off';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stimulator = cerestim96();
        end

        % Close request function: CereStimDBSUIFigure
        function CereStimDBSUIFigureCloseRequest(app, event)
            app.stimulator.disconnect();
            delete(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            dev_ix = find(strcmpi(app.DropDown.Items, app.DropDown.Value)) - 1;
            app.stimulator.selectDevice(dev_ix);
            if (app.stimulator.isConnected())
                app.stimulator.disconnect();
                app.GenerateButton.Enable = 'off';
                app.ConnectButton.Text = 'Connect';
                app.StatusLamp.Color = 'red';
            else
                app.stimulator.connect();
                app.GenerateButton.Enable = 'on';
                app.ConnectButton.Text = 'Disconnect';
                app.StatusLamp.Color = 'yellow';
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            [min_amp, max_amp] = app.stimulator.getMinMaxAmplitude();
            stim_freq = app.FrequencyHzEditField.Value;
            p1_width = app.WidthuSEditField.Value;
            p1_amp = app.AmplitudeuAEditField.Value;
            final_dur = app.FinalDursEditField.Value;
            ramp_dur = app.RampDursEditField.Value;
            elec = app.ElectrodeEditField.Value;
            pol = app.PolarityDropDown.Value;
            interphase = app.InterphaseDropDown.Value;
            
            full_cycle_us = 1E6 / stim_freq;
            min_interphase = 53;
            
            if strcmpi(pol, 'Monophasic-Like')
                p2_max_width = full_cycle_us - p1_width - 2*min_interphase;
                p2_amp = (p1_width * p1_amp) / p2_max_width;
                p2_amp = max([p2_amp min_amp]);
                p2_width = (p1_width * p1_amp) / p2_amp;
                is_ana = 0;
            else
                p2_width = p1_width;
                p2_amp = p1_amp;
                is_ana = ~any(strcmpi(pol, 'Cathodic First'));
            end
            
            if strcmpi(interphase, 'Max Sep')
                interphase = (full_cycle_us - p1_width - p2_width) / 2;
            else
                interphase = min_interphase;
            end
            
            % Configure final waveform
            n_final_pulses = ceil(final_dur * stim_freq);
            if n_final_pulses > 255
                n_final_stims = ceil(n_final_pulses / 255);
                n_final_pulses = 255;
            else
                n_final_stims = 1;
            end
            app.stimulator.setStimPattern(...
                'waveform', 15,...
                'polarity', int16(is_ana),...
                'pulses', n_final_pulses,...
                'amp1', p1_amp,...
                'amp2', p2_amp,...
                'width1', p1_width,...
                'width2', p2_width,...
                'interphase', interphase,...
                'frequency', stim_freq);
            
            % TODO: Calculate RAMP and add in waveform positions 1-14
            
            app.stimulator.beginSequence();
            % TODO: Add ramp stims
            for final_ix = 1:n_final_stims
                app.stimulator.autoStim(elec, 15);
            end
            app.stimulator.endSequence();
            
            app.StartButton.Enable = 'on';
            app.StatusLamp.Color = 'blue';
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            device_list = app.stimulator.scanForDevices();
            dd_items = cell(1, length(device_list));
            for dev_ix = 1:length(device_list)
                dd_items{dev_ix} = num2str(device_list(dev_ix));
            end
            app.DropDown.Items = dd_items;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatusLamp.Color = 'green';
            app.stimulator.play(1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stimulator.stop();
            app.StatusLamp.Color = 'blue';
        end

        % Value changed function: PolarityDropDown
        function PolarityDropDownValueChanged(app, event)
            value = app.PolarityDropDown.Value;
            app.params_changed();
        end

        % Value changed function: RampDursEditField
        function RampDursEditFieldValueChanged(app, event)
            value = app.RampDursEditField.Value;
            app.params_changed();
        end

        % Value changed function: FinalDursEditField
        function FinalDursEditFieldValueChanged(app, event)
            value = app.FinalDursEditField.Value;
            app.params_changed();
        end

        % Value changed function: AmplitudeuAEditField
        function AmplitudeuAEditFieldValueChanged(app, event)
            value = app.AmplitudeuAEditField.Value;
            app.params_changed();
        end

        % Value changed function: InterphaseDropDown
        function InterphaseDropDownValueChanged(app, event)
            value = app.InterphaseDropDown.Value;
            app.params_changed();
        end

        % Value changed function: WidthuSEditField
        function WidthuSEditFieldValueChanged(app, event)
            value = app.WidthuSEditField.Value;
            app.params_changed();
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            value = app.FrequencyHzEditField.Value;
            app.params_changed();            
        end

        % Value changed function: ElectrodeEditField
        function ElectrodeEditFieldValueChanged(app, event)
            value = app.ElectrodeEditField.Value;
            app.params_changed();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CereStimDBSUIFigure = uifigure;
            app.CereStimDBSUIFigure.Name = 'CereStim DBS';
            app.CereStimDBSUIFigure.CloseRequestFcn = createCallbackFcn(app, @CereStimDBSUIFigureCloseRequest, true);
            app.RefreshButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DropDown = uidropdown(app.CereStimDBSUIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.ConnectButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.AmplitudeuAEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.AmplitudeuAEditFieldLabel.Text = 'Amplitude (uA)';
            app.AmplitudeuAEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.AmplitudeuAEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeuAEditFieldValueChanged, true);
            app.AmplitudeuAEditField.Limits = [100 10000];
            app.AmplitudeuAEditField.ValueDisplayFormat = '%.0f';
            app.AmplitudeuAEditField.Value = 500;
            app.WidthuSEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.WidthuSEditFieldLabel.Text = 'Width (uS)';
            app.WidthuSEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.WidthuSEditField.ValueChangedFcn = createCallbackFcn(app, @WidthuSEditFieldValueChanged, true);
            app.WidthuSEditField.Limits = [44 65535];
            app.WidthuSEditField.ValueDisplayFormat = '%.0f';
            app.WidthuSEditField.Value = 100;
            app.FrequencyHzEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Limits = [4 5000];
            app.FrequencyHzEditField.ValueDisplayFormat = '%11.12g';
            app.FrequencyHzEditField.Value = 130.0052;
            app.FinalDursEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.FinalDursEditFieldLabel.Text = 'Final Dur. (s)';
            app.FinalDursEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.FinalDursEditField.ValueChangedFcn = createCallbackFcn(app, @FinalDursEditFieldValueChanged, true);
            app.FinalDursEditField.Limits = [1 Inf];
            app.FinalDursEditField.Value = 30;
            app.RampDursEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.RampDursEditFieldLabel.Text = 'Ramp Dur. (s)';
            app.RampDursEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.RampDursEditField.ValueChangedFcn = createCallbackFcn(app, @RampDursEditFieldValueChanged, true);
            app.RampDursEditField.Limits = [0 5];
            app.ElectrodeEditFieldLabel = uilabel(app.CereStimDBSUIFigure);
            app.ElectrodeEditFieldLabel.Text = 'Electrode';
            app.ElectrodeEditField = uieditfield(app.CereStimDBSUIFigure, 'numeric');
            app.ElectrodeEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodeEditFieldValueChanged, true);
            app.ElectrodeEditField.Limits = [1 96];
            app.ElectrodeEditField.ValueDisplayFormat = '%.0f';
            app.ElectrodeEditField.Value = 1;
            app.InterphaseDropDownLabel = uilabel(app.CereStimDBSUIFigure);
            app.InterphaseDropDownLabel.Text = 'Interphase';
            app.InterphaseDropDown = uidropdown(app.CereStimDBSUIFigure);
            app.InterphaseDropDown.Items = {'Max Sep', 'Min Sep'};
            app.InterphaseDropDown.ValueChangedFcn = createCallbackFcn(app, @InterphaseDropDownValueChanged, true);
            app.InterphaseDropDown.Value = 'Max Sep';
            app.PolarityDropDownLabel = uilabel(app.CereStimDBSUIFigure);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.CereStimDBSUIFigure);
            app.PolarityDropDown.Items = {'Cathodic First', 'Anodic First', 'Monophasic-Like'};
            app.PolarityDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarityDropDownValueChanged, true);
            app.PolarityDropDown.Value = 'Cathodic First';
            app.GenerateButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.StartButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.CereStimDBSUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StatusLampLabel = uilabel(app.CereStimDBSUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.CereStimDBSUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = cerestim_dbs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CereStimDBSUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CereStimDBSUIFigure)
        end
    end
end